classdef PatientsDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsDisplayUIFigure         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        NumberofExperimentsEditField    matlab.ui.control.NumericEditField
        NumberofExperimentsEditFieldLabel  matlab.ui.control.Label
        Panel2_4                        matlab.ui.container.Panel
        ScenarioSettingsPanel           matlab.ui.container.Panel
        ClosingIntervalminEditField     matlab.ui.control.NumericEditField
        ClosingIntervalminEditFieldLabel  matlab.ui.control.Label
        AppointmentIntervalminEditField  matlab.ui.control.NumericEditField
        AppointmentIntervalminEditFieldLabel  matlab.ui.control.Label
        PatientsperIntervalEditField    matlab.ui.control.NumericEditField
        PatientsperIntervalEditFieldLabel  matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        DoctorSelectionMethodDropDown   matlab.ui.control.DropDown
        DoctorSelectionMethodDropDownLabel  matlab.ui.control.Label
        SimultionTimeminEditField       matlab.ui.control.NumericEditField
        SimultionTimeminEditFieldLabel  matlab.ui.control.Label
        DoctorsDropDown                 matlab.ui.control.DropDown
        DoctorsDropDownLabel            matlab.ui.control.Label
        InputParametersLabel            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PlotTab                         matlab.ui.container.Tab
        BinWidthSlider                  matlab.ui.control.Slider
        BinWidthSliderLabel             matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        HistogramButton                 matlab.ui.control.RadioButton
        ScatterButton                   matlab.ui.control.RadioButton
        GraphSwitch                     matlab.ui.control.Switch
        UIAxes                          matlab.ui.control.UIAxes
        DataTab                         matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        Data
        SelectedGenders
        SelectedColors
        BinWidth
        Histogram = gobjects(0)
        displayedIndices
    end
    
    methods (Access = private)
        
        function NBins = numhistbins(app,data)
            % Utility function to compute the number of histogram bins 
            binwidth = app.BinWidth;
            range2plot =  floor(min(data)):binwidth:ceil(max(data));
            NBins = size(range2plot,2);
        end
        
        function annotateScatterPlot(app)
            % Update X and Y Labels
            app.UIAxes.XLabel.String = "Weight";
            app.UIAxes.YLabel.String = app.GraphSwitch.Value;
            % Dont show the histogram slider
            app.BinWidthSliderLabel.Visible = "off";
            app.BinWidthSlider.Visible = "off";
        end
        
        function annotateHistogram(app)
           
            % Update X and Y Labels
            app.UIAxes.XLabel.String = app.GraphSwitch.Value;
            app.UIAxes.YLabel.String = "# of Patients";
            
            % Show histogram slider
            app.BinWidthSliderLabel.Visible = "on";
            app.BinWidthSlider.Visible = "on";
        end
        
        function filterData(app)
            % Utility function to filter the data according to the controls
            
            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            tempIndices = ones([size(app.Data,1),1]);
            
            % Append a column to tempIndices to indicate data that satisfies the smoker control
            if app.NoCheckBox.Value && ~app.YesCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 0];
            elseif app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 1];
            elseif  ~app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the gender control
            if app.MaleCheckBox.Value && ~app.FemaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Male"];
            elseif app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Female"];
            elseif  ~app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the location control
            if app.HospitalNameDropDown.Value ~= "All"
                tempIndices = [tempIndices, app.Data.Location == string(app.HospitalNameDropDown.Value)];
            end
            
            % Determine which data points satisfy all requirements
            app.displayedIndices = (sum(tempIndices,2)/size(tempIndices,2) == 1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            load("patients.mat","LastName","Gender","Smoker","Age","Height","Weight","Diastolic","Systolic","Location");
            
            % Store the data in a table and display it in one of the App's tabs.
            app.Data = table(LastName,Gender,Smoker,Age,Height,Weight,Diastolic,Systolic,Location);
            app.UITable.Data = app.Data;
            app.BinWidth = app.BinWidthSlider.Value;
            
            % Update the axes with the corresponding data.
            updateSelectedGenders(app)
            refreshplot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsDisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {400, 400};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {283, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: BinWidthSlider
        function SliderValueChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes.
            app.BinWidth = event.Value;
            for ii=1:length(app.Histogram)
                app.Histogram(ii).NumBins = numhistbins(app,app.Histogram(ii).Data);
            end
        end

        % Callback function: BinWidthSlider, ButtonGroup, GraphSwitch, 
        % ...and 1 other component
        function refreshplot(app, event)
            Genders = app.SelectedGenders;
            Colors = app.SelectedColors;
            
            % Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,"on")
            app.Histogram = gobjects(0);
            
            % Select relevant segment of data
            xdata = app.Data.Weight;
            ydata = app.Data.(app.GraphSwitch.Value);
            
            % Filter the data according to the controls
            filterData(app);
            
            % Create either a scatter plot or histogram, based on selection
            switch app.ButtonGroup.SelectedObject.Text
                
                case 'Scatter'
                    % Build a scatter plot for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        scatter(app.UIAxes,xdata((selectedpatients)),ydata(selectedpatients),Colors{ii});
                    end
                    annotateScatterPlot(app)
                    
                case 'Histogram'
                    % Build a histogram for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        NBins = numhistbins(app,ydata(selectedpatients));
                        h = histogram(app.UIAxes,ydata(selectedpatients),NBins,"BinLimits",[floor(min(ydata)) ceil(max(ydata))]);
                        h.EdgeColor = Colors{ii};
                        h.FaceColor = Colors{ii};
                        app.Histogram = [app.Histogram h];
                    end
                    annotateHistogram(app)
                    
            end
            
            % Update the table to show only the data that satisfies the controls
            app.UITable.Data = app.Data(app.displayedIndices,:);
            drawnow;
        end

        % Callback function
        function updateSelectedGenders(app, event)
            % List which genders and colors to use
            % % % Genders = [];
            % % % Colors = [];
            % % % Smoker = [];
            % % % 
            % % % if app.MaleCheckBox.Value
            % % %     Genders = "Male";
            % % %     Colors = "blue";
            % % % end
            % % % if app.FemaleCheckBox.Value
            % % %     Genders = [Genders "Female"];
            % % %     Colors = [Colors "red"];
            % % % end
            % % % if app.YesCheckBox.Value
            % % %     Smoker = "Yes";
            % % % end
            % % % if app.NoCheckBox.Value
            % % %     Smoker = [Smoker "No"];
            % % % end
            % % % 
            % % % if isempty(Genders) || isempty(Smoker)
            % % %     % Disable the switches and buttons if they were on
            % % %     app.GraphSwitch.Enable = "off";
            % % %     app.ScatterButton.Enable = "off";
            % % %     app.HistogramButton.Enable = "off";
            % % %     app.BinWidthSliderLabel.Enable = "off";
            % % %     app.BinWidthSlider.Enable = "off";
            % % % else
            % % %     % Enable the switches and buttons if they were off
            % % %     app.GraphSwitch.Enable = "on";
            % % %     app.ScatterButton.Enable = "on";
            % % %     app.HistogramButton.Enable = "on";
            % % %     app.BinWidthSliderLabel.Enable = "on";
            % % %     app.BinWidthSlider.Enable = "on";
            % % % end
            % % % app.SelectedGenders = Genders;
            % % % app.SelectedColors = Colors;
            
            refreshplot(app)
        end

        % Callback function
        function ButtonPushed(app, event)
            myClinic = Clinic(4,60);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsDisplayUIFigure = uifigure('Visible', 'off');
            app.PatientsDisplayUIFigure.Name = 'Patients Display';
            app.PatientsDisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputParametersLabel = uilabel(app.LeftPanel);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Clinic Settings';
            app.DoctorsDropDownLabel = uilabel(app.Panel2_4);
            app.DoctorsDropDownLabel.Text = 'Doctors';
            app.DoctorsDropDown = uidropdown(app.Panel2_4);
            app.DoctorsDropDown.Items = {'4', '1', '2', '3', '4', '5'};
            app.DoctorsDropDown.Value = '1';
            app.SimultionTimeminEditFieldLabel = uilabel(app.Panel2_4);
            app.SimultionTimeminEditFieldLabel.Text = 'Simultion Time (min)';
            app.SimultionTimeminEditField = uieditfield(app.Panel2_4, 'numeric');
            app.DoctorSelectionMethodDropDownLabel = uilabel(app.Panel2_4);
            app.DoctorSelectionMethodDropDownLabel.Text = 'Doctor Selection Method';
            app.DoctorSelectionMethodDropDown = uidropdown(app.Panel2_4);
            app.DoctorSelectionMethodDropDown.Items = {'minWorkload', 'random', 'priority', 'circular'};
            app.DoctorSelectionMethodDropDown.Value = 'minWorkload';
            app.ScenarioSettingsPanel = uipanel(app.Panel2_4);
            app.ScenarioSettingsPanel.Title = 'Scenario Settings';
            app.ScenarioDropDownLabel = uilabel(app.ScenarioSettingsPanel);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.ScenarioSettingsPanel);
            app.ScenarioDropDown.Items = {'1', '2', '3'};
            app.ScenarioDropDown.Value = '1';
            app.PatientsperIntervalEditFieldLabel = uilabel(app.ScenarioSettingsPanel);
            app.PatientsperIntervalEditFieldLabel.Text = 'Patients per Interval';
            app.PatientsperIntervalEditField = uieditfield(app.ScenarioSettingsPanel, 'numeric');
            app.AppointmentIntervalminEditFieldLabel = uilabel(app.ScenarioSettingsPanel);
            app.AppointmentIntervalminEditFieldLabel.Text = 'Appointment Interval (min)';
            app.AppointmentIntervalminEditField = uieditfield(app.ScenarioSettingsPanel, 'numeric');
            app.ClosingIntervalminEditFieldLabel = uilabel(app.ScenarioSettingsPanel);
            app.ClosingIntervalminEditFieldLabel.Text = 'Closing Interval (min)';
            app.ClosingIntervalminEditField = uieditfield(app.ScenarioSettingsPanel, 'numeric');
            app.NumberofExperimentsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofExperimentsEditFieldLabel.Text = 'Number of Experiments';
            app.NumberofExperimentsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.Text = 'Run';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            xlabel(app.UIAxes, 'Weight')
            ylabel(app.UIAxes, 'Diastolic')
            app.GraphSwitch = uiswitch(app.PlotTab, 'slider');
            app.GraphSwitch.Items = {'Queue Length', 'Doctors Utilization'};
            app.GraphSwitch.ValueChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.GraphSwitch.Value = 'Queue Length';
            app.ButtonGroup = uibuttongroup(app.PlotTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.ButtonGroup.Title = 'Plotting Options';
            app.ScatterButton = uiradiobutton(app.ButtonGroup);
            app.ScatterButton.Text = 'Scatter';
            app.ScatterButton.Value = true;
            app.HistogramButton = uiradiobutton(app.ButtonGroup);
            app.HistogramButton.Text = 'Histogram';
            app.BinWidthSliderLabel = uilabel(app.PlotTab);
            app.BinWidthSliderLabel.Text = 'Bin Width';
            app.BinWidthSlider = uislider(app.PlotTab);
            app.BinWidthSlider.Limits = [1 11];
            app.BinWidthSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.BinWidthSlider.ValueChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.BinWidthSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.BinWidthSlider.MinorTicks = [];
            app.BinWidthSlider.Value = 5;
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Last Name'; 'Gender'; 'Smoker'; 'Age'; 'Height'; 'Weight'; 'Diastolic'; 'Systolic'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @refreshplot, true);
            % Show the figure after all components are created
            app.PatientsDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatientsDisplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsDisplayUIFigure)
        end
    end
end